classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        STARTButton          matlab.ui.control.Button
        EMERGENCYSTOPButton  matlab.ui.control.Button
        TextArea             matlab.ui.control.TextArea
        Label                matlab.ui.control.Label
        DOBOT1Panel          matlab.ui.container.Panel
        Link1Slider_3Label   matlab.ui.control.Label
        Link1Slider_3        matlab.ui.control.Slider
        Link2Slider_3Label   matlab.ui.control.Label
        Link2Slider_3        matlab.ui.control.Slider
        Link3Slider_3Label   matlab.ui.control.Label
        Link3Slider_3        matlab.ui.control.Slider
        DOBOT2Panel          matlab.ui.container.Panel
        Link1Slider_4Label   matlab.ui.control.Label
        Link1Slider_4        matlab.ui.control.Slider
        Link2Slider_4Label   matlab.ui.control.Label
        Link2Slider_4        matlab.ui.control.Slider
        Link3Slider_4Label   matlab.ui.control.Label
        Link3Slider_4        matlab.ui.control.Slider
    end

    
    properties (Access = private)
        Dobot1 Dobot1 % Description
        Dobot2 Dobot2
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         dobot1 = app.Dobot1;
%             dobot2 = Dobot2;  
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
qr = deg2rad([0,-42.5,-50,0,0]); % Ready pose

%% Ready Position 
steps = 5;
qr(1,4) = 0 - qr(1,2) - qr(1,3); % Ensures that the end effector always points down
dobot1.model.animate(jtraj(dobot1.model.getpos,qr,steps));
dobot2.model.animate(jtraj(dobot1.model.getpos,qr,steps));
%% Plotting Tokens
tokens_h = {0 0 0 0 0 0 0 0 0}; % Cell array for storing Token handles
tokenVertices = {0 0 0 0 0 0 0 0 0}; % Cell array for storing Token vertices
for i = 1:1:9 % Plotting 9 Tokens
    if i <= 4
        tokens_h{i} = PlaceObject('Token O.ply'); % Importing Token-O
    else
        tokens_h{i} = PlaceObject('Token X.ply'); % Importing Token-X
    end
    tokenVertices{i} = get(tokens_h{i},'Vertices'); % Extracting vertices data
    transformedVertices = [tokenVertices{i},ones(size(tokenVertices{i},1),1)] * tokens{i}'; % Transforming vertices
    set(tokens_h{i},'Vertices',transformedVertices(:,1:3)); % Updating object location
    drawnow; % Update simulation
    pause(0.01); % Wait before execution
end
%% Dual Dobot Operation
input('Press <Enter> to begin Dobot gameplay'); % Wait for user input

steps = 30; % Setting robot speed
gripOffset = 0.1; % Setting offset for gripper
oldQ = qr;   % Setting initial pose
for i = 1:1:4   % Collecting and placing tokens
    pickTr = tokens{i}*transl(0,0,gripOffset);   % Calculating pickup point
    dropTr = boardSpots{i}*transl(0,0,gripOffset);   % Calculating drop off point
    newQ = dobot1.model.ikcon(pickTr,oldQ); % Calculating required pose
    newTr = dobot1.model.fkine(newQ);   % Calculating forward transform
    if abs(pickTr(1,4) - newTr(1,4)) <= 0.1 && abs(pickTr(2,4) - newTr(2,4)) <= 0.1 && abs(pickTr(3,4) - newTr(3,4)) <= 0.1 % Checking reachability
       disp(['Token',num2str(i),' is within reach.']); % Display message
       if newTr(3,4) >= 0.4   % Check to see if the transform is above table
          dobot1.model.animate(jtraj(oldQ,newQ,steps)); % Move robot to token
          oldQ = newQ; % Update robot pose
          newQ = dobot1.model.ikcon(dropTr,oldQ); % Calculating required pose
          qMatrix = jtraj(oldQ,newQ,steps); % Calculating trajectory
          for j = 1:1:steps % Moving robot with token to board spot
              dobot1.model.animate(qMatrix(j,:)) % Update robot pose                                                               % Move token 
              transformedVertices=[tokenVertices{i},ones(size(tokenVertices{i},1),1)]*(dobot1.model.fkine(qMatrix(j,:))*transl(0,0,-gripOffset))'; 
              set(tokens_h{i},'Vertices',transformedVertices(:,1:3)); % Updating token location
              drawnow;  % Update simulation
          end
          oldQ = newQ; % Update robot pose
       else
           warning(['A pose for brick',num2str(i),' is not possible.']); % Display message
       end
    else
       warning(['Token',num2str(i),' is out of reach.']); % Display message
    end
end
dobot1.model.animate(jtraj(dobot1.model.getpos,qr,steps)); % Returning to ready pose
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
           
        end

        % Value changed function: Link1Slider_3
        function Link1SliderValueChanged(app, event)
startupFcn(app);

value = app.Link1Slider.Value;
            qLink1 = deg2rad([value]);
            q1(1,1) = qLink1;
            steps = 5;
            dobot1.model.animate(jtraj(dobot1.model.getpos,q1,steps));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Dual Dobot Gameplay'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DOBOT1Panel = uipanel(app.UIFigure);
            app.DOBOT1Panel.Title = 'DOBOT1';
            app.Link1Slider_3Label = uilabel(app.DOBOT1Panel);
            app.Link1Slider_3Label.Text = 'Link 1';
            app.Link1Slider_3 = uislider(app.DOBOT1Panel);
            app.Link1Slider_3.Limits = [-90 90];
            app.Link1Slider_3.MajorTicks = [-90 -45 0 45 90];
            app.Link1Slider_3.ValueChangedFcn = createCallbackFcn(app, @Link1SliderValueChanged, true);
            app.Link2Slider_3Label = uilabel(app.DOBOT1Panel);
            app.Link2Slider_3Label.Text = 'Link 2';
            app.Link2Slider_3 = uislider(app.DOBOT1Panel);
            app.Link2Slider_3.Limits = [-42.5 42.5];
            app.Link2Slider_3.MajorTicks = [-42.5 -20 0 20 42.5];
            app.Link2Slider_3.MajorTickLabels = {'-42.5', '-20', '0', '20', '42.5'};
            app.Link2Slider_3.MinorTicks = [-42.5 -40 -35 -30 -25 -20 -15 -10 -5 5 10 15 20 25 30 35 40];
            app.Link3Slider_3Label = uilabel(app.DOBOT1Panel);
            app.Link3Slider_3Label.Text = 'Link 3';
            app.Link3Slider_3 = uislider(app.DOBOT1Panel);
            app.Link3Slider_3.Limits = [-50 50];
            app.Link3Slider_3.MajorTicks = [-50 -25 0 25 50];
            app.DOBOT2Panel = uipanel(app.UIFigure);
            app.DOBOT2Panel.Title = 'DOBOT2';
            app.Link1Slider_4Label = uilabel(app.DOBOT2Panel);
            app.Link1Slider_4Label.Text = 'Link 1';
            app.Link1Slider_4 = uislider(app.DOBOT2Panel);
            app.Link1Slider_4.Limits = [-90 90];
            app.Link1Slider_4.MajorTicks = [-90 -45 0 45 90];
            app.Link2Slider_4Label = uilabel(app.DOBOT2Panel);
            app.Link2Slider_4Label.Text = 'Link 2';
            app.Link2Slider_4 = uislider(app.DOBOT2Panel);
            app.Link2Slider_4.Limits = [-42.5 42.5];
            app.Link2Slider_4.MajorTicks = [-42.5 -20 0 20 42.5];
            app.Link2Slider_4.MajorTickLabels = {'-42.5', '-20', '0', '20', '42.5'};
            app.Link2Slider_4.MinorTicks = [-42.5 -40 -35 -30 -25 -20 -15 -10 -5 5 10 15 20 25 30 35 40];
            app.Link3Slider_4Label = uilabel(app.DOBOT2Panel);
            app.Link3Slider_4Label.Text = 'Link 3';
            app.Link3Slider_4 = uislider(app.DOBOT2Panel);
            app.Link3Slider_4.Limits = [-50 50];
            app.Link3Slider_4.MajorTicks = [-50 -25 0 25 50];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end